classdef FinalApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        LeftPanel                  matlab.ui.container.Panel
        TabGroup                   matlab.ui.container.TabGroup
        PIDTab                     matlab.ui.container.Tab
        PlotButton                 matlab.ui.control.Button
        KILabel                    matlab.ui.control.Label
        KI                         matlab.ui.control.NumericEditField
        KDEditFieldLabel           matlab.ui.control.Label
        KD                         matlab.ui.control.NumericEditField
        KPEditFieldLabel           matlab.ui.control.Label
        KP                         matlab.ui.control.NumericEditField
        UncompenstatedSystemResponseLabel  matlab.ui.control.Label
        KP1Label                   matlab.ui.control.Label
        KD0Label                   matlab.ui.control.Label
        KI0Label                   matlab.ui.control.Label
        SuggestedGainValuesLabel   matlab.ui.control.Label
        KP53Label                  matlab.ui.control.Label
        KI194Label                 matlab.ui.control.Label
        KD361Label                 matlab.ui.control.Label
        StateSpaceTab              matlab.ui.container.Tab
        RootLocusTab               matlab.ui.container.Tab
        RightPanel                 matlab.ui.container.Panel
        RiseTimeLabel              matlab.ui.control.Label
        RiseTime                   matlab.ui.control.TextArea
        SettlingTimeTextAreaLabel  matlab.ui.control.Label
        SettlingTime               matlab.ui.control.TextArea
        OvershootLabel             matlab.ui.control.Label
        Overshoot                  matlab.ui.control.TextArea
        SteadyStateErrorLabel      matlab.ui.control.Label
        SteadyStateError           matlab.ui.control.TextArea
        StepPlot                   matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: PlotButton, StepPlot
        function PlotButtonPushed(app, event)
            KP = app.KP.Value;
            KI = app.KI.Value;
            KD = app.KD.Value;
            s = tf('s');
            P_aircraft = (1.151*s + 0.1774)/(s^3 + .739*s^2 +0.921*s);
            PID_controller = KP + KI/s +KD*s;
            Gs = P_aircraft*PID_controller;
            Ts = feedback(Gs,1);
            [y, t] = step(.2*Ts);
            sserror = abs(.2-y(end))*100;
            sserror = num2str(sserror);
            plot(app.StepPlot,t,y);
            S = stepinfo(.2*Ts);
            S = struct2cell(S);
            S = num2str(cell2mat(S));
            app.RiseTime.Value = S(1,:);
            app.SettlingTime.Value = S(2,:);
            app.Overshoot.Value = S(5,:);
            app.SteadyStateError.Value = sserror;
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {400, 400};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {364, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.LeftPanel);
            app.PIDTab = uitab(app.TabGroup);
            app.PIDTab.Title = 'PID';
            app.PlotButton = uibutton(app.PIDTab, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.KILabel = uilabel(app.PIDTab);
            app.KILabel.Text = 'KI ';
            app.KI = uieditfield(app.PIDTab, 'numeric');
            app.KDEditFieldLabel = uilabel(app.PIDTab);
            app.KDEditFieldLabel.Text = 'KD';
            app.KD = uieditfield(app.PIDTab, 'numeric');
            app.KPEditFieldLabel = uilabel(app.PIDTab);
            app.KPEditFieldLabel.Text = 'KP';
            app.KP = uieditfield(app.PIDTab, 'numeric');
            app.KP.Value = 1;
            app.UncompenstatedSystemResponseLabel = uilabel(app.PIDTab);
            app.UncompenstatedSystemResponseLabel.Text = 'Uncompenstated System Response:';
            app.KP1Label = uilabel(app.PIDTab);
            app.KP1Label.Text = 'KP = 1';
            app.KD0Label = uilabel(app.PIDTab);
            app.KD0Label.Text = 'KD = 0';
            app.KI0Label = uilabel(app.PIDTab);
            app.KI0Label.Text = 'KI  = 0';
            app.SuggestedGainValuesLabel = uilabel(app.PIDTab);
            app.SuggestedGainValuesLabel.Text = 'Suggested Gain Values:';
            app.KP53Label = uilabel(app.PIDTab);
            app.KP53Label.Text = 'KP = 5.3';
            app.KI194Label = uilabel(app.PIDTab);
            app.KI194Label.Text = 'KI = 1.94';
            app.KD361Label = uilabel(app.PIDTab);
            app.KD361Label.Text = 'KD = 3.61';
            app.StateSpaceTab = uitab(app.TabGroup);
            app.StateSpaceTab.Title = 'State-Space';
            app.RootLocusTab = uitab(app.TabGroup);
            app.RootLocusTab.Title = 'Root Locus';
            app.RightPanel = uipanel(app.GridLayout);
            app.RiseTimeLabel = uilabel(app.RightPanel);
            app.RiseTimeLabel.Text = 'Rise Time';
            app.RiseTime = uitextarea(app.RightPanel);
            app.SettlingTimeTextAreaLabel = uilabel(app.RightPanel);
            app.SettlingTimeTextAreaLabel.Text = 'Settling Time';
            app.SettlingTime = uitextarea(app.RightPanel);
            app.OvershootLabel = uilabel(app.RightPanel);
            app.OvershootLabel.Text = 'Overshoot(%)';
            app.Overshoot = uitextarea(app.RightPanel);
            app.SteadyStateErrorLabel = uilabel(app.RightPanel);
            app.SteadyStateErrorLabel.Text = 'Steady State Error(%)';
            app.SteadyStateError = uitextarea(app.RightPanel);
            app.StepPlot = uiaxes(app.RightPanel);
            title(app.StepPlot, 'Closed-Loop Step Response of Aircraft Pitch System')
            xlabel(app.StepPlot, 'Time(sec)')
            ylabel(app.StepPlot, 'Amplitude(m)')
            zlabel(app.StepPlot, 'Z')
            app.StepPlot.ButtonDownFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FinalApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end